classdef Circle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Image                      matlab.ui.control.Image
        CIRCLELabel                matlab.ui.control.Label
        EnterradiusEditFieldLabel  matlab.ui.control.Label
        radius                     matlab.ui.control.NumericEditField
        EnterXcoordinateofthecenterwithrespecttooriginLabel  matlab.ui.control.Label
        Xcenter                    matlab.ui.control.NumericEditField
        EnterYcoordinateofthecenterwithrespecttooriginLabel  matlab.ui.control.Label
        Ycenter                    matlab.ui.control.NumericEditField
        graph                      matlab.ui.control.UIAxes
        CALCULATEButton            matlab.ui.control.Button
        CentroidEditFieldLabel     matlab.ui.control.Label
        centroid                   matlab.ui.control.EditField
        MomentofInertiaalongXxisandYaxisLabel  matlab.ui.control.Label
        MomentXY                   matlab.ui.control.EditField
        MomentofinertiaalogcentroidalaxisLabel  matlab.ui.control.Label
        MomentC                    matlab.ui.control.EditField
        BackButton                 matlab.ui.control.Button
        ExitButton                 matlab.ui.control.Button
        ENTERINPUTLabel            matlab.ui.control.Label
        OUTPUTLabel                matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CALCULATEButton
        function CALCULATEButtonPushed(app, event)
            r=app.radius.Value;
            d=2*r;
            c(1,1)=app.Xcenter.Value;
            c(1,2)=app.Ycenter.Value;
            A=pi*r*r;
            Centroid=c;
            
            th=linspace(0,2*pi,100);
            x=c(1,1);
            y=c(1,2);
            plot(app.graph,x+r*cos(th),y+r*sin(th));
            axis(app.graph,'equal');
            
            Ic1=(pi*d*d*d*d)/64;
            Ic=[Ic1 Ic1];
            outIc=mat2str(Ic);
            
            I= Ic+ A*(c.*c);
            outI=mat2str(I);
            outc=mat2str(Centroid);
            app.centroid.Value=outc;
            app.MomentXY.Value=outI;
            app.MomentC.Value=outIc;
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            StartMenu
            delete(app)
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'bg8.jpg';
            app.CIRCLELabel = uilabel(app.UIFigure);
            app.CIRCLELabel.Text = 'CIRCLE';
            app.EnterradiusEditFieldLabel = uilabel(app.UIFigure);
            app.EnterradiusEditFieldLabel.Text = 'Enter radius:';
            app.radius = uieditfield(app.UIFigure, 'numeric');
            app.EnterXcoordinateofthecenterwithrespecttooriginLabel = uilabel(app.UIFigure);
            app.EnterXcoordinateofthecenterwithrespecttooriginLabel.Text = 'Enter X-coordinate of the center with respect to origin: ';
            app.Xcenter = uieditfield(app.UIFigure, 'numeric');
            app.EnterYcoordinateofthecenterwithrespecttooriginLabel = uilabel(app.UIFigure);
            app.EnterYcoordinateofthecenterwithrespecttooriginLabel.Text = 'Enter Y-coordinate of the center with respect to origin: ';
            app.Ycenter = uieditfield(app.UIFigure, 'numeric');
            app.graph = uiaxes(app.UIFigure);
            title(app.graph, 'Title')
            xlabel(app.graph, 'X')
            ylabel(app.graph, 'Y')
            app.CALCULATEButton = uibutton(app.UIFigure, 'push');
            app.CALCULATEButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULATEButtonPushed, true);
            app.CALCULATEButton.Text = 'CALCULATE!';
            app.CentroidEditFieldLabel = uilabel(app.UIFigure);
            app.CentroidEditFieldLabel.Text = 'Centroid:';
            app.centroid = uieditfield(app.UIFigure, 'text');
            app.MomentofInertiaalongXxisandYaxisLabel = uilabel(app.UIFigure);
            app.MomentofInertiaalongXxisandYaxisLabel.Text = {'Moment of Inertia along '; 'X-xis and Y-axis:'};
            app.MomentXY = uieditfield(app.UIFigure, 'text');
            app.MomentofinertiaalogcentroidalaxisLabel = uilabel(app.UIFigure);
            app.MomentofinertiaalogcentroidalaxisLabel.Text = {'Moment of inertia alog'; ' centroidal axis:'};
            app.MomentC = uieditfield(app.UIFigure, 'text');
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.ENTERINPUTLabel = uilabel(app.UIFigure);
            app.ENTERINPUTLabel.Text = 'ENTER INPUT:';
            app.OUTPUTLabel = uilabel(app.UIFigure);
            app.OUTPUTLabel.Text = 'OUTPUT:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Circle

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end