classdef CircleWithHole < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Image                         matlab.ui.control.Image
        CIRCLEWITHHOLELabel           matlab.ui.control.Label
        ENTERINPUTSOFMAINCIRCLELabel  matlab.ui.control.Label
        ENTERINPUTSOFHOLELabel        matlab.ui.control.Label
        OUTPUTLabel                   matlab.ui.control.Label
        EnterradiusEditFieldLabel     matlab.ui.control.Label
        radius                        matlab.ui.control.NumericEditField
        EnterthexcoordinateofthecenterwithrespecttooriginEditFieldLabel  matlab.ui.control.Label
        Xcoordinate                   matlab.ui.control.NumericEditField
        EntertheycoordinateofthecenterwithrespecttooriginEditFieldLabel  matlab.ui.control.Label
        Ycoordinate                   matlab.ui.control.NumericEditField
        EnterradiusEditField_2Label   matlab.ui.control.Label
        radius_hole                   matlab.ui.control.NumericEditField
        EnterEditField_5Label         matlab.ui.control.Label
        Xcoordinate_hole              matlab.ui.control.NumericEditField
        EnterEditField_6Label         matlab.ui.control.Label
        CentroidEditFieldLabel        matlab.ui.control.Label
        centroid                      matlab.ui.control.EditField
        Ycoordinate_hole              matlab.ui.control.NumericEditField
        centroid_2                    matlab.ui.control.EditField
        MomentofinertiaaboutXaxisandYaxisLabel  matlab.ui.control.Label
        centroid_3                    matlab.ui.control.EditField
        MomentofinertiaaboutCentroidalaxisLabel  matlab.ui.control.Label
        Calculate                     matlab.ui.control.Button
        Exit                          matlab.ui.control.Button
        Back                          matlab.ui.control.Button
        graph                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Calculate
        function CalculateButtonPushed(app, event)
            r1=app.radius.Value;
            x1=app.Xcoordinate.Value;
            y1=app.Ycoordinate.Value;
            
            r2=app.radius_hole.Value;
            x2=app.Xcoordinate_hole.Value;
            y2=app.Ycoordinate_hole.Value;
            
            c1(1,1)=x1;
            c1(1,2)=y1;
            c2(1,1)=x2;
            c2(1,2)=y2;
            
            centroid1 = c1;
            area1=(pi*(r1^2));
            
            centroid2 = c2;
            area2=(pi*(r2^2));
            d = sqrt( (x2-x1)^2 + (y2-y1)^2 );

            hold(app.graph,'on');
            axis(app.graph,'equal');
            
            th = 0:pi/6:2*pi;
            xunit1 = r1 * cos(th) + x1;
            yunit1= r1 * sin(th) + y1;
            plot(app.graph,xunit1, yunit1);
            
            th = 0:pi/6:2*pi;
            xunit2= r2 * cos(th) + x2;
            yunit2= r2 * sin(th) + y2;
            plot(app.graph,xunit2, yunit2);
            
if r1 > ( d + r2 ) 
   flag=1;
end

if(flag==1)
    centroid=((centroid1*area1-centroid2*area2)/(area1-area2));
    outC=mat2str(centroid);
    app.centroid.Value=outC;
    Cx=centroid(1);
    Cy=centroid(2);
    d1x = Cx-c1(1);
    d1y = Cy-c1(2);
    d2x = Cx-c2(1);
    d2y = Cy-c2(2);
    
    Ic1 = (4*pi*r1*r1*r1*r1)/16;
    Ic2 = (4*pi*r2*r2*r2*r2)/16;
    Icx = Ic1-Ic2+area1*d1x*d1x - area2*d2x*d2x;
    Icy = Ic1-Ic2+area1*d1y*d1y - area2*d2y*d2y;
    
    E= area1-area2;
    Ic = [Icx Icy];
    Ixx= Icx+ E*Cx*Cx;
    Iyy = Icy+E*Cy*Cy;
    I=[Ixx Iyy];
    outIc=mat2str(Ic);
    app.centroid_2.Value=outIc;
    outI=mat2str(centroid);
    app.centroid_3.Value=outI

end
           
        end

        % Button pushed function: Back
        function BackButtonPushed(app, event)
            StartMenu
            delete(app)
        end

        % Button pushed function: Exit
        function ExitButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'bg8.jpg';
            app.CIRCLEWITHHOLELabel = uilabel(app.UIFigure);
            app.CIRCLEWITHHOLELabel.Text = 'CIRCLE WITH HOLE';
            app.ENTERINPUTSOFMAINCIRCLELabel = uilabel(app.UIFigure);
            app.ENTERINPUTSOFMAINCIRCLELabel.Text = 'ENTER INPUTS OF MAIN CIRCLE:';
            app.ENTERINPUTSOFHOLELabel = uilabel(app.UIFigure);
            app.ENTERINPUTSOFHOLELabel.Text = 'ENTER INPUTS OF HOLE:';
            app.OUTPUTLabel = uilabel(app.UIFigure);
            app.OUTPUTLabel.Text = 'OUTPUT:';
            app.EnterradiusEditFieldLabel = uilabel(app.UIFigure);
            app.EnterradiusEditFieldLabel.Text = 'Enter radius:';
            app.radius = uieditfield(app.UIFigure, 'numeric');
            app.EnterthexcoordinateofthecenterwithrespecttooriginEditFieldLabel = uilabel(app.UIFigure);
            app.EnterthexcoordinateofthecenterwithrespecttooriginEditFieldLabel.Text = 'Enter the x coordinate of the center with respect to origin:';
            app.Xcoordinate = uieditfield(app.UIFigure, 'numeric');
            app.EntertheycoordinateofthecenterwithrespecttooriginEditFieldLabel = uilabel(app.UIFigure);
            app.EntertheycoordinateofthecenterwithrespecttooriginEditFieldLabel.Text = 'Enter the y coordinate of the center with respect to origin:';
            app.Ycoordinate = uieditfield(app.UIFigure, 'numeric');
            app.EnterradiusEditField_2Label = uilabel(app.UIFigure);
            app.EnterradiusEditField_2Label.Text = 'Enter radius:';
            app.radius_hole = uieditfield(app.UIFigure, 'numeric');
            app.EnterEditField_5Label = uilabel(app.UIFigure);
            app.EnterEditField_5Label.Text = 'Enter the y coordinate of the center with respect to origin:';
            app.Xcoordinate_hole = uieditfield(app.UIFigure, 'numeric');
            app.EnterEditField_6Label = uilabel(app.UIFigure);
            app.EnterEditField_6Label.Text = 'Enter the x coordinate of the center with respect to origin:';
            app.Ycoordinate_hole = uieditfield(app.UIFigure, 'numeric');
            app.CentroidEditFieldLabel = uilabel(app.UIFigure);
            app.CentroidEditFieldLabel.Text = 'Centroid:';
            app.centroid = uieditfield(app.UIFigure, 'text');
            app.Calculate = uibutton(app.UIFigure, 'push');
            app.Calculate.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.Calculate.Text = 'CALCULATE!';
            app.Exit = uibutton(app.UIFigure, 'push');
            app.Exit.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.Exit.Text = 'Exit';
            app.Back = uibutton(app.UIFigure, 'push');
            app.Back.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.Back.Text = 'Back';
            app.graph = uiaxes(app.UIFigure);
            title(app.graph, 'Title')
            xlabel(app.graph, 'X')
            ylabel(app.graph, 'Y')
            app.centroid_2 = uieditfield(app.UIFigure, 'text');
            app.MomentofinertiaaboutXaxisandYaxisLabel = uilabel(app.UIFigure);
            app.MomentofinertiaaboutXaxisandYaxisLabel.Text = 'Moment of inertia about X-axis and Y-axis';
            app.centroid_3 = uieditfield(app.UIFigure, 'text');
            app.MomentofinertiaaboutCentroidalaxisLabel = uilabel(app.UIFigure);
            app.MomentofinertiaaboutCentroidalaxisLabel.Text = 'Moment of inertia about Centroidal axis: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CircleWithHole

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end