classdef CirclewithSlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Image                         matlab.ui.control.Image
        CIRCLEWITHSLOTLabel           matlab.ui.control.Label
        ENTERINPUTFORCIRCLELabel      matlab.ui.control.Label
        ENTERINPUTFORSLOTLabel        matlab.ui.control.Label
        OUTPUTLabel                   matlab.ui.control.Label
        EnterradiusEditFieldLabel     matlab.ui.control.Label
        radius                        matlab.ui.control.NumericEditField
        EnterthexcoordinateofthecenterEditFieldLabel  matlab.ui.control.Label
        Xcoordinates                  matlab.ui.control.NumericEditField
        EntertheycoordinateofthecenterEditFieldLabel  matlab.ui.control.Label
        Ycoordinates                  matlab.ui.control.NumericEditField
        EnterXcoordinatesEditFieldLabel  matlab.ui.control.Label
        Xcoordinates_Poly             matlab.ui.control.EditField
        EnterYcoordinatesEditFieldLabel  matlab.ui.control.Label
        Ycoordnates_Poly              matlab.ui.control.EditField
        CentroidEditFieldLabel        matlab.ui.control.Label
        centroid                      matlab.ui.control.EditField
        MomentofinertiaaboutXaxisandYaxisEditFieldLabel  matlab.ui.control.Label
        MomentXY                      matlab.ui.control.EditField
        MomentofinertiaaboutCentroidalaxisEditFieldLabel  matlab.ui.control.Label
        MomentC                       matlab.ui.control.EditField
        Exit                          matlab.ui.control.Button
        Back                          matlab.ui.control.Button
        EnternoofsidesEditFieldLabel  matlab.ui.control.Label
        EnternoofsidesEditField       matlab.ui.control.NumericEditField
        calculate                     matlab.ui.control.Button
        graph                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: calculate
        function calculateButtonPushed(app, event)
            r=app.radius.Value;
            cx=app.Xcoordinates.Value;
            cy=app.Ycoordinates.Value;
            
            N=app.EnternoofsidesEditField.Value;
            Xvalstring = app.Xcoordinates_Poly.Value;
            Xvalparts = regexp(Xvalstring, '[ ,]', 'split');
            X = str2double(Xvalparts);
            
            Yvalstring = app.Xcoordinates_Poly.Value;
            Yvalparts = regexp(Yvalstring, '[ ,]', 'split');
            Y = str2double(Yvalparts);

            hold(app.graph,'on');
            axis(app.graph,'equal')
            
            pgon=polyshape(X,Y);
            plot(app.graph,pgon);
            
            th = 0:pi/6:2*pi;
            xunit = r * cos(th) + cx;
            yunit = r * sin(th) + cy;
            plot(app.graph,xunit, yunit);
            
            areaCir= (pi*((r)^2));
            centroidCir = [cx cy];
            tp=cy+r;
            rp=cx+r;
            lp=cx-r;
            bp=cy-r;
            
            q=[cx lp rp];
w=[tp cy cy];
Icir=(5*pi*(r^4))/4;

% check if inputs are same size

% if ~isequal( size(X), size(Y) )
%   error('X and Y must be the same size');
% end
% temporarily shift data to mean of vertices for improved accuracy
xm = mean(X);
ym = mean(Y);
x = X - xm;
y = Y - ym;
%check if slot is inside circle
counter=0;
flg=0;
for s=1:N
    re=iscircle(q,w,X(1,s),Y(1,s));
    if(re==-1)
        counter=counter+1;
    end
end
if(counter==N)
    flg=1;
end
if(flg==1)
% summations for CCW boundary
xp = x([2:end 1]);
yp = y([2:end 1]);
a = x.*yp - xp.*y;
A = sum( a ) /2;
xc = sum( (x+xp).*a  ) /6/A;
yc = sum( (y+yp).*a  ) /6/A;
Ixx = (sum( (y.*y +y.*yp + yp.*yp).*a  ) /12)*(-1);
Iyy = (sum( (x.*x +x.*xp + xp.*xp).*a  ) /12)*(-1);


% centroidal moments

Iuu = Ixx - A*yc*yc;
Ivv = Iyy - A*xc*xc;

% replace mean of vertices

x_cen = xc + xm;
y_cen = yc + ym;


%finding centroid of figure
Centroidx=(cx*areaCir-x_cen*A)/(areaCir-x_cen);
Centroidy=(cy*areaCir-y_cen*A)/(areaCir-y_cen);
C=[Centroidx Centroidy];
outC=mat2str(C);
app.centroid.Value=outC;
dcx=Centroidx-cx;
dcy=Centroidy-cy;
dpx=Centroidx-x_cen;
dpy=Centroidy-y_cen;

%finding mi of figure

mix=Icir-Iuu + areaCir*dcx*dcx - A*dpx*dpx;
miy=Icir-Ivv + areaCir*dcy*dcy - A*dpy*dpy;
Ic=[mix miy];
outIc=mat2str(Ic);
app.MomentC.Value=outIc;

E=areaCir -A;
%along x and y axes

Ixx=mix + E*Centroidx*Centroidx;
Iyy=miy + E*Centroidy*Centroidy;
Ii=[Ixx Iyy];
outIi=mat2str(Ii);
app.MomentXY.Value=outIi;
end
function result=iscircle(lol1,lol2,a,b)
    x1=lol1(1);y1=lol2(1);
    x2=lol1(2);y2=lol2(2);
    x3=lol1(3);y3=lol2(3);
    k=((x1-x2)*(x2*x2-x3*x3+y2*y2-y3*y3)-(x2-x3)*(x1*x1-x2*x2+y1*y1-y2*y2))/((2)*((y2-y3)*(x1-x2)-(y1-y2)*(x2-x3)));
    
    h=((y1-y2)*(y1+y2-2*k))/((2)*(x1-x2))+(x1+x2)/2;
    
    r=sqrt((x3-h)*(x3-h)+(y3-k)*(y3-k));
    
    val=(a-h)*(a-h)+(b-k)*(b-k)-r*r;
    
    result=sign(val);
    if(result==1)
        disp("invalid");
    end
    
end

        end

        % Button pushed function: Exit
        function ExitButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: Back
        function BackButtonPushed(app, event)
            StartMenu
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'bg8.jpg';
            app.CIRCLEWITHSLOTLabel = uilabel(app.UIFigure);
            app.CIRCLEWITHSLOTLabel.Text = 'CIRCLE WITH SLOT';
            app.ENTERINPUTFORCIRCLELabel = uilabel(app.UIFigure);
            app.ENTERINPUTFORCIRCLELabel.Text = 'ENTER INPUT FOR CIRCLE:';
            app.ENTERINPUTFORSLOTLabel = uilabel(app.UIFigure);
            app.ENTERINPUTFORSLOTLabel.Text = 'ENTER INPUT FOR SLOT:';
            app.OUTPUTLabel = uilabel(app.UIFigure);
            app.OUTPUTLabel.Text = 'OUTPUT:';
            app.EnterradiusEditFieldLabel = uilabel(app.UIFigure);
            app.EnterradiusEditFieldLabel.Text = 'Enter radius:';
            app.radius = uieditfield(app.UIFigure, 'numeric');
            app.EnterthexcoordinateofthecenterEditFieldLabel = uilabel(app.UIFigure);
            app.EnterthexcoordinateofthecenterEditFieldLabel.Text = 'Enter the x coordinate of the center:';
            app.Xcoordinates = uieditfield(app.UIFigure, 'numeric');
            app.EntertheycoordinateofthecenterEditFieldLabel = uilabel(app.UIFigure);
            app.EntertheycoordinateofthecenterEditFieldLabel.Text = 'Enter the y coordinate of the center:';
            app.Ycoordinates = uieditfield(app.UIFigure, 'numeric');
            app.EnterXcoordinatesEditFieldLabel = uilabel(app.UIFigure);
            app.EnterXcoordinatesEditFieldLabel.Text = 'Enter X-coordinates:';
            app.Xcoordinates_Poly = uieditfield(app.UIFigure, 'text');
            app.EnterYcoordinatesEditFieldLabel = uilabel(app.UIFigure);
            app.EnterYcoordinatesEditFieldLabel.Text = 'Enter Y-coordinates:';
            app.Ycoordnates_Poly = uieditfield(app.UIFigure, 'text');
            app.CentroidEditFieldLabel = uilabel(app.UIFigure);
            app.CentroidEditFieldLabel.Text = 'Centroid';
            app.centroid = uieditfield(app.UIFigure, 'text');
            app.MomentofinertiaaboutXaxisandYaxisEditFieldLabel = uilabel(app.UIFigure);
            app.MomentofinertiaaboutXaxisandYaxisEditFieldLabel.Text = 'Moment of inertia about X-axis and Y-axis:';
            app.MomentXY = uieditfield(app.UIFigure, 'text');
            app.MomentofinertiaaboutCentroidalaxisEditFieldLabel = uilabel(app.UIFigure);
            app.MomentofinertiaaboutCentroidalaxisEditFieldLabel.Text = 'Moment of inertia about Centroidal-axis';
            app.MomentC = uieditfield(app.UIFigure, 'text');
            app.Exit = uibutton(app.UIFigure, 'push');
            app.Exit.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.Exit.Text = 'Exit';
            app.Back = uibutton(app.UIFigure, 'push');
            app.Back.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.Back.Text = 'Back';
            app.calculate = uibutton(app.UIFigure, 'push');
            app.calculate.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed, true);
            app.calculate.Text = 'CALCULATE!';
            app.graph = uiaxes(app.UIFigure);
            title(app.graph, 'Title')
            xlabel(app.graph, 'X')
            ylabel(app.graph, 'Y')
            app.EnternoofsidesEditFieldLabel = uilabel(app.UIFigure);
            app.EnternoofsidesEditFieldLabel.Text = 'Enter no of sides:';
            app.EnternoofsidesEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CirclewithSlot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end