classdef Polygon1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Image                          matlab.ui.control.Image
        PolygonLabel                   matlab.ui.control.Label
        CALCULATEButton                matlab.ui.control.Button
        EnternumberofsidesEditFieldLabel  matlab.ui.control.Label
        NoofSides                      matlab.ui.control.NumericEditField
        EnterxcoordinatesEditFieldLabel  matlab.ui.control.Label
        Xcoordinates                   matlab.ui.control.EditField
        EnterycoordinatesEditFieldLabel  matlab.ui.control.Label
        Ycoordinates                   matlab.ui.control.EditField
        graph                          matlab.ui.control.UIAxes
        CentroidEditFieldLabel         matlab.ui.control.Label
        centroid                       matlab.ui.control.EditField
        MomentofinertiaonXaxisandYaxisLabel  matlab.ui.control.Label
        MomentXY                       matlab.ui.control.EditField
        MomentofinertiaoncentralaxisLabel  matlab.ui.control.Label
        MomentC                        matlab.ui.control.EditField
        ExitButton                     matlab.ui.control.Button
        BackButton                     matlab.ui.control.Button
        ENTERINPUTSFORTHEPOLYGONLabel  matlab.ui.control.Label
        OUTPUTLabel                    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CALCULATEButton
        function CALCULATEButtonPushed(app, event)
            Xvalstring =app.Xcoordinates.Value;
            Xvalparts = regexp(Xvalstring, '[ ,]', 'split');
            x = str2double(Xvalparts);
            
            Yvalstring =app.Ycoordinates.Value;
            Yvalparts = regexp(Yvalstring, '[ ,]', 'split');
            y = str2double(Yvalparts);
            
            n=app.NoofSides.Value;
            
            % check if inputs are same size
            if ~isequal( size(x), size(y) )
              app.centroid.Value='X and Y must be the same size';
            end
            
            axis(app.graph,'equal');
            pgon = polyshape(x,y);
            plot(app.graph,pgon)
             
            % temporarily shift data to mean of vertices for improved accuracy
            xm = mean(x);
            ym = mean(y);
            x = x - xm;
            y = y - ym;
              
            % summations for CCW boundary
            xp = x( [2:end 1] );
            yp = y( [2:end 1] );
            a = x.*yp - xp.*y;
             
            A = sum( a ) /2;
            xc = sum( (x+xp).*a  ) /6/A;
            yc = sum( (y+yp).*a  ) /6/A;
            Ixx = (sum( (y.*y +y.*yp + yp.*yp).*a  ) /12)*(-1);
            Iyy = (sum( (x.*x +x.*xp + xp.*xp).*a  ) /12)*(-1);
             
            % centroidal moments
            Iuu = Ixx + A*yc*yc;
            Ivv = Iyy + A*xc*xc;
             
            % replace mean of vertices
            x_cen = xc + xm;
            y_cen = yc + ym;
            Ixx = (Iuu + A*y_cen*y_cen)*(-1);
            Iyy = (Ivv + A*x_cen*x_cen)*(-1);
            
            % return values
            disp("Centroid point:");
            Centroid = [x_cen  y_cen];
            disp("Moment of Inertia on X axis and  Y axis:");
            I = [Ixx Iyy];
            disp("Moment of Inertia on Centroidal Axis:");
            Ic = [Iuu Ivv];
            
            c=mat2str(Centroid);
            mxy=mat2str(I);
            mc=mat2str(Ic);
            app.centroid.Value=c;
            app.MomentXY.Value=mxy;
            app.MomentC.Value=mc;
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            StartMenu
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'bg8.jpg';
            app.PolygonLabel = uilabel(app.UIFigure);
            app.PolygonLabel.Text = 'Polygon';
            app.CALCULATEButton = uibutton(app.UIFigure, 'push');
            app.CALCULATEButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULATEButtonPushed, true);
            app.CALCULATEButton.Text = 'CALCULATE!';
            app.EnternumberofsidesEditFieldLabel = uilabel(app.UIFigure);
            app.EnternumberofsidesEditFieldLabel.Text = 'Enter number of sides: ';
            app.NoofSides = uieditfield(app.UIFigure, 'numeric');
            app.EnterxcoordinatesEditFieldLabel = uilabel(app.UIFigure);
            app.EnterxcoordinatesEditFieldLabel.Text = 'Enter x-coordinates: ';
            app.Xcoordinates = uieditfield(app.UIFigure, 'text');
            app.EnterycoordinatesEditFieldLabel = uilabel(app.UIFigure);
            app.EnterycoordinatesEditFieldLabel.Text = 'Enter y-coordinates: ';
            app.Ycoordinates = uieditfield(app.UIFigure, 'text');
            app.graph = uiaxes(app.UIFigure);
            title(app.graph, 'Title')
            xlabel(app.graph, 'X')
            ylabel(app.graph, 'Y')
            app.CentroidEditFieldLabel = uilabel(app.UIFigure);
            app.CentroidEditFieldLabel.Text = 'Centroid: ';
            app.centroid = uieditfield(app.UIFigure, 'text');
            app.MomentofinertiaonXaxisandYaxisLabel = uilabel(app.UIFigure);
            app.MomentofinertiaonXaxisandYaxisLabel.Text = {'Moment of inertia on '; 'X-axis and Y-axis:'};
            app.MomentXY = uieditfield(app.UIFigure, 'text');
            app.MomentofinertiaoncentralaxisLabel = uilabel(app.UIFigure);
            app.MomentofinertiaoncentralaxisLabel.Text = {'Moment of inertia on'; ' central axis:'};
            app.MomentC = uieditfield(app.UIFigure, 'text');
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.ENTERINPUTSFORTHEPOLYGONLabel = uilabel(app.UIFigure);
            app.ENTERINPUTSFORTHEPOLYGONLabel.Text = 'ENTER INPUTS FOR THE POLYGON:';
            app.OUTPUTLabel = uilabel(app.UIFigure);
            app.OUTPUTLabel.Text = 'OUTPUT:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Polygon1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end