classdef PolygonWithHole < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Image                          matlab.ui.control.Image
        PolygonwithholeLabel           matlab.ui.control.Label
        EnternoofsidesEditFieldLabel   matlab.ui.control.Label
        NoofSides                      matlab.ui.control.NumericEditField
        EnterxcoordinatesLabel         matlab.ui.control.Label
        Xcoordinates                   matlab.ui.control.EditField
        EnterycoordinatesLabel         matlab.ui.control.Label
        Ycoordinates                   matlab.ui.control.EditField
        CALCULATE                      matlab.ui.control.Button
        EnterXcoordinateofcentreofcircleEditFieldLabel  matlab.ui.control.Label
        Xcoordinate_circle             matlab.ui.control.NumericEditField
        EnterYcoordinateofcentreofcircleEditFieldLabel  matlab.ui.control.Label
        Ycoordinate_circle             matlab.ui.control.NumericEditField
        EnterradiusEditFieldLabel      matlab.ui.control.Label
        radius                         matlab.ui.control.NumericEditField
        graph                          matlab.ui.control.UIAxes
        CentroidEditFieldLabel         matlab.ui.control.Label
        centroid                       matlab.ui.control.EditField
        MomentofinertiaalongXaxisandYaxisEditFieldLabel  matlab.ui.control.Label
        MomentXY                       matlab.ui.control.EditField
        MomentofinertiaoncentroidalaxisEditFieldLabel  matlab.ui.control.Label
        MomentC                        matlab.ui.control.EditField
        BackButton                     matlab.ui.control.Button
        ExitButton                     matlab.ui.control.Button
        ENTERINPUTSOFMAINPOLYGONLabel  matlab.ui.control.Label
        ENTERINPUTSOFHOLELabel         matlab.ui.control.Label
        OUTPUTLabel                    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CALCULATE
        function CALCULATEButtonPushed(app, event)
            Xvalstring = app.Xcoordinates.Value;
            Xvalparts = regexp(Xvalstring, '[ ,]', 'split');
            X = str2double(Xvalparts);
                        
            Yvalstring = app.Ycoordinates.Value;
            Yvalparts = regexp(Yvalstring, '[ ,]', 'split');
            Y = str2double(Yvalparts);
            
            N=app.NoofSides.Value;
            
            r=app.radius.Value;
            cx=app.Xcoordinate_circle.Value;
            cy=app.Ycoordinate_circle.Value;
            
            hold(app.graph,'on');
            axis(app.graph,'equal')
            
            pgon=polyshape(X,Y);
            plot(app.graph,pgon);
            
            th = 0:pi/6:2*pi;
            xunit = r * cos(th) + cx;
            yunit = r * sin(th) + cy;
            plot(app.graph,xunit, yunit);
            
            % check if inputs are same size

            if ~isequal( size(X), size(Y) )
                app.centroid.Value='X and Y must be the same size';
                app.MomentXY.Value='X and Y must be the same size';
                app.MomentC.Value='X and Y must be the same size';
            end
            
            % temporarily shift data to mean of vertices for improved accuracy
            
            xm = mean(X);
            ym = mean(Y);
            x = X - xm;
            y = Y - ym;

            % summations for CCW boundary

            xp = x( [2:end 1] );
            yp = y( [2:end 1] );
            a = x.*yp - xp.*y;
            A = sum( a ) /2;
            xc = sum( (x+xp).*a  ) /6/A;
            yc = sum( (y+yp).*a  ) /6/A;
            Ixx = (sum( (y.*y +y.*yp + yp.*yp).*a  ) /12)*(-1);
            Iyy = (sum( (x.*x +x.*xp + xp.*xp).*a  ) /12)*(-1);
        
            
            % centroidal moments
            
            Iuu = Ixx - A*yc*yc;
            Ivv = Iyy - A*xc*xc;
            
            % replace mean of vertices
            
            x_cen = xc + xm;
            y_cen = yc + ym;
            Ixx = (Iuu + A*y_cen*y_cen)*(-1);
            Iyy = (Ivv + A*x_cen*x_cen)*(-1);
            
            %For the circular hole
            Ac=(5*pi*(r^4))/4;

            %Verification whether the slot is inside
            
            t=0;
            for s=1:N
                d = sqrt((X(s)-cx)^2+(Y(s)-cy)^2);
                if (d>r)
                    t = t+1;
                else
                    app.centroid.Value="Hole is outside";
                    app.MomentXY.Value="Hole is outside";
                    app.MomentC.Value="Hole is outside";
                    break
                end
            end
            
             E = A-Ac;
            
            if(t == N)
                Cex = ((A*x_cen-Ac*cx)/A-Ac);
                Cey = ((A*y_cen-Ac*cy)/A-Ac);
                dcx = (Cex - cx);
                dcy = (Cey - cy);
                drx = (Cex - x_cen);
                dry = (Cey - y_cen);
                MIX=Iuu-Ac+(A*drx*drx)-(Ac*dcx*dcx);
                MIY=Ivv-Ac+(A*dry*dry)-(Ac*dcy*dcy);
                Ixx = MIX+ E*(Cex*Cex);
                Iyy = MIY+ E*(Cey*Cey);
             
            % return values
           
               Centroid = [Cex  Cey];
               Ic = [MIX MIY];
               I = [ Ixx  Iyy];
               outc=mat2str(Centroid);
               outIc=mat2str(Ic);
               outI=mat2str(I);
               app.centroid.Value=outc;
               app.MomentXY.Value=outI;
               app.MomentC.Value=outIc;
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            StartMenu
            delete(app)
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'bg8.jpg';
            app.PolygonwithholeLabel = uilabel(app.UIFigure);
            app.PolygonwithholeLabel.Text = 'Polygon with hole';
            app.EnternoofsidesEditFieldLabel = uilabel(app.UIFigure);
            app.EnternoofsidesEditFieldLabel.Text = 'Enter no. of sides:';
            app.NoofSides = uieditfield(app.UIFigure, 'numeric');
            app.EnterxcoordinatesLabel = uilabel(app.UIFigure);
            app.EnterxcoordinatesLabel.Text = 'Enter x-coordinates:';
            app.Xcoordinates = uieditfield(app.UIFigure, 'text');
            app.EnterycoordinatesLabel = uilabel(app.UIFigure);
            app.EnterycoordinatesLabel.Text = 'Enter y-coordinates:';
            app.Ycoordinates = uieditfield(app.UIFigure, 'text');
            app.CALCULATE = uibutton(app.UIFigure, 'push');
            app.CALCULATE.ButtonPushedFcn = createCallbackFcn(app, @CALCULATEButtonPushed, true);
            app.CALCULATE.Text = 'CALCULATE!';
            app.EnterXcoordinateofcentreofcircleEditFieldLabel = uilabel(app.UIFigure);
            app.EnterXcoordinateofcentreofcircleEditFieldLabel.Text = 'Enter X coordinate of centre of circle: ';
            app.Xcoordinate_circle = uieditfield(app.UIFigure, 'numeric');
            app.EnterYcoordinateofcentreofcircleEditFieldLabel = uilabel(app.UIFigure);
            app.EnterYcoordinateofcentreofcircleEditFieldLabel.Text = 'Enter Y coordinate of centre of circle: ';
            app.Ycoordinate_circle = uieditfield(app.UIFigure, 'numeric');
            app.EnterradiusEditFieldLabel = uilabel(app.UIFigure);
            app.EnterradiusEditFieldLabel.Text = 'Enter radius';
            app.radius = uieditfield(app.UIFigure, 'numeric');
            app.graph = uiaxes(app.UIFigure);
            title(app.graph, 'YOUR FIGURE')
            xlabel(app.graph, 'X')
            ylabel(app.graph, 'Y')
            app.CentroidEditFieldLabel = uilabel(app.UIFigure);
            app.CentroidEditFieldLabel.Text = 'Centroid: ';
            app.centroid = uieditfield(app.UIFigure, 'text');
            app.MomentofinertiaalongXaxisandYaxisEditFieldLabel = uilabel(app.UIFigure);
            app.MomentofinertiaalongXaxisandYaxisEditFieldLabel.Text = {'Moment of inertia along'; ' X-axis and Y-axis: '};
            app.MomentXY = uieditfield(app.UIFigure, 'text');
            app.MomentofinertiaoncentroidalaxisEditFieldLabel = uilabel(app.UIFigure);
            app.MomentofinertiaoncentroidalaxisEditFieldLabel.Text = {'Moment of inertia on '; 'centroidal-axis:'};
            app.MomentC = uieditfield(app.UIFigure, 'text');
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.ENTERINPUTSOFMAINPOLYGONLabel = uilabel(app.UIFigure);
            app.ENTERINPUTSOFMAINPOLYGONLabel.Text = 'ENTER INPUTS OF MAIN POLYGON:';
            app.ENTERINPUTSOFHOLELabel = uilabel(app.UIFigure);
            app.ENTERINPUTSOFHOLELabel.Text = 'ENTER INPUTS OF HOLE:';
            app.OUTPUTLabel = uilabel(app.UIFigure);
            app.OUTPUTLabel.Text = 'OUTPUT:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PolygonWithHole

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end