classdef PolywithPoly < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image                           matlab.ui.control.Image
        POLYGONWITHPOLYGONALSLOTLabel   matlab.ui.control.Label
        EnternumberofsidesEditFieldLabel  matlab.ui.control.Label
        NoofSides                       matlab.ui.control.NumericEditField
        EnterxcoordinatesEditFieldLabel  matlab.ui.control.Label
        Xcoordinates                    matlab.ui.control.EditField
        EnterycoordinatesEditFieldLabel  matlab.ui.control.Label
        Ycoordinates                    matlab.ui.control.EditField
        EnternumberofsidesforslotEditFieldLabel  matlab.ui.control.Label
        NoofSides_slot                  matlab.ui.control.NumericEditField
        EnterxcoordinatesEditFieldLabel_2  matlab.ui.control.Label
        Xcoordinates_slot               matlab.ui.control.EditField
        EnterycoordinatesEditField_2Label  matlab.ui.control.Label
        Ycoordinates_slot               matlab.ui.control.EditField
        CALCULATE                       matlab.ui.control.Button
        graph                           matlab.ui.control.UIAxes
        CentroidEditFieldLabel          matlab.ui.control.Label
        centroid                        matlab.ui.control.EditField
        MomentofinertiaonxaxisandyaxisEditFieldLabel  matlab.ui.control.Label
        MomentXY                        matlab.ui.control.EditField
        MomentofinertiaoncentroidalaxisLabel  matlab.ui.control.Label
        MomentC                         matlab.ui.control.EditField
        ExitButton                      matlab.ui.control.Button
        BackButton                      matlab.ui.control.Button
        ENTERINPUTSOFMAINPOLYGONLabel   matlab.ui.control.Label
        OUTPUTLabel                     matlab.ui.control.Label
        ENTERINPUTSOFINNERPOLYGONLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CALCULATE
        function CALCULATEButtonPushed(app, event)
            Xvalstring = app.Xcoordinates.Value;
            Xvalparts = regexp(Xvalstring, '[ ,]', 'split');
            X = str2double(Xvalparts);
                        
            Yvalstring = app.Ycoordinates.Value;
            Yvalparts = regexp(Yvalstring, '[ ,]', 'split');
            Y = str2double(Yvalparts);
            
            Xvalstring = app.Xcoordinates_slot.Value;
            Xvalparts = regexp(Xvalstring, '[ ,]', 'split');
            P = str2double(Xvalparts);
                        
            Yvalstring = app.Ycoordinates_slot.Value;
            Yvalparts = regexp(Yvalstring, '[ ,]', 'split');
            Q = str2double(Yvalparts);
            
            N=app.NoofSides_slot.Value
            
            hold(app.graph,'on');
            axis(app.graph,'on');
            pgon1=polyshape(X,Y);
            plot(app.graph,pgon1);
            pgon2=polyshape(P,Q);
            plot(app.graph,pgon2);
            
            
            % check if inputs are same size
            if ~isequal( size(X), size(Y) )
              app.centroid.Value=('X and Y must be the same size');
              app.MomentXY.Value=('X and Y must be the same size');
              app.MomentC.Value=('X and Y must be the same size');
            end
             
            % temporarily shift data to mean of vertices for improved accuracy
            xm = mean(X);
            ym = mean(Y);
            x = X - xm;
            y = Y - ym;
            
            % summations for CCW boundary
            xp = x( [2:end 1] );
            yp = y( [2:end 1] );
            a = x.*yp - xp.*y;
             
            A = (sum( a ) /2)*-1;
            xc = sum( (x+xp).*a  ) /6/A;
            yc = sum( (y+yp).*a  ) /6/A;
            Ixx = (sum( (y.*y +y.*yp + yp.*yp).*a  ) /12)*-1;
            Iyy = (sum( (x.*x +x.*xp + xp.*xp).*a  ) /12)*-1;
             
            % centroidal moments
            Iuu = Ixx - A*yc*yc;
            Ivv = Iyy - A*xc*xc;
             
            % replace mean of vertices
            x_cen = xc + xm;
            y_cen = yc + ym;
            Ixx = (Iuu + A*y_cen*y_cen)*(-1);
            Iyy = (Ivv + A*x_cen*x_cen)*(-1);
            
            %SLOT

        % check if inputs are same size
        if ~isequal( size(P), size(Q) )
          app.centroid.Value=('P and Q must be the same size');
          app.MomentXY.Value=('P and Q must be the same size');
          app.MomentC.Value=('P and Q must be the same size');
        end
        
        flag=1;
        % TO CHECK IF SLOT IS INSIDE:
            for s=1:N
                in=inpolygon(P(1,s),Q(1,s),X,Y);
                if(~in)
                    flag=2;
                    app.centroid.Value("Slot outside figure");
                    app.MomentXY.Value("Slot outside figure");
                    app.MomentC.Value("Slot outside figure");
                    break;
                end
            end
        % temporarily shift data to mean of vertices for improved accuracy
        pm = mean(P);
        qm = mean(Q);
        p = P - pm;
        q = Q - qm;
        
        % summations for CCW boundary
        pp = p( [2:end 1] );
        qp = q( [2:end 1] );
        b = p.*qp - pp.*q;
         
        B = (sum( b ) /2)*-1;
        pc = sum( (p+pp).*b  ) /6/B;
        qc = sum( (q+qp).*b  ) /6/B;
        Ipx = (sum( (q.*q +q.*qp + qp.*qp).*b  ) /12)*-1;
        Iqy = (sum( (p.*p +p.*pp + pp.*pp).*b  ) /12)*-1;
         
        % centroidal moments
        Ipu = Ipx - B*qc*qc;
        Iqv = Iqy - B*pc*pc;
         
        % replace mean of vertices
        p_cen = pc + pm;
        q_cen = qc + qm;
        Ipx = (Ipu + B*q_cen*q_cen)*-1;
        Ipy = (Iqv + B*p_cen*p_cen)*-1;
        
        %To Find Centroid of the figure
        if(flag==1)
        	cx=((A*x_cen)-(B*p_cen))/(A-B);
        	cy=((A*y_cen)-(B*q_cen))/(A-B);
            C=[cx cy];
            outc=mat2str(C);
            app.centroid.Value=outc;
        end
        E=A-B;
        
        %To Find Moment of the figure  
        if(flag==1)
        	dx=cx-x_cen;
        	dy=cy-y_cen;
        	dsx=cx-p_cen;
        	dsy=cy-q_cen;	
            Icx =(Iuu-Ipu)+(A*dx*dx)-(B*dsx*dsx);
        	Icy =(Ivv-Iqv)+(A*dy*dy)-(B*dsy*dsy);
            Ixx=Icx + E*cy*cy;
            Iyy=Icy + E*cx*cx;
            Ic=[Icx Icy];
            I=[Ixx Iyy];
            outI=mat2str(I);
            outIc=mat2str(Ic);
            app.MomentXY.Value=outI;
            app.MomentC.Value=outIc;
        end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            StartMenu
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'bg8.jpg';
            app.POLYGONWITHPOLYGONALSLOTLabel = uilabel(app.UIFigure);
            app.POLYGONWITHPOLYGONALSLOTLabel.Text = 'POLYGON WITH POLYGONAL SLOT';
            app.EnternumberofsidesEditFieldLabel = uilabel(app.UIFigure);
            app.EnternumberofsidesEditFieldLabel.Text = 'Enter number of sides: ';
            app.NoofSides = uieditfield(app.UIFigure, 'numeric');
            app.EnterxcoordinatesEditFieldLabel = uilabel(app.UIFigure);
            app.EnterxcoordinatesEditFieldLabel.Text = 'Enter x-coordinates: ';
            app.Xcoordinates = uieditfield(app.UIFigure, 'text');
            app.EnterycoordinatesEditFieldLabel = uilabel(app.UIFigure);
            app.EnterycoordinatesEditFieldLabel.Text = 'Enter y-coordinates:';
            app.Ycoordinates = uieditfield(app.UIFigure, 'text');
            app.EnternumberofsidesforslotEditFieldLabel = uilabel(app.UIFigure);
            app.EnternumberofsidesforslotEditFieldLabel.Text = 'Enter number of sides for slot:';
            app.NoofSides_slot = uieditfield(app.UIFigure, 'numeric');
            app.EnterxcoordinatesEditFieldLabel_2 = uilabel(app.UIFigure);
            app.EnterxcoordinatesEditFieldLabel_2.Text = 'Enter x-coordinates: ';
            app.Xcoordinates_slot = uieditfield(app.UIFigure, 'text');
            app.EnterycoordinatesEditField_2Label = uilabel(app.UIFigure);
            app.EnterycoordinatesEditField_2Label.Text = 'Enter y-coordinates:';
            app.Ycoordinates_slot = uieditfield(app.UIFigure, 'text');
            app.CALCULATE = uibutton(app.UIFigure, 'push');
            app.CALCULATE.ButtonPushedFcn = createCallbackFcn(app, @CALCULATEButtonPushed, true);
            app.CALCULATE.Text = 'CALCULATE!';
            app.graph = uiaxes(app.UIFigure);
            title(app.graph, 'Title')
            xlabel(app.graph, 'X')
            ylabel(app.graph, 'Y')
            app.CentroidEditFieldLabel = uilabel(app.UIFigure);
            app.CentroidEditFieldLabel.Text = 'Centroid:';
            app.centroid = uieditfield(app.UIFigure, 'text');
            app.MomentofinertiaonxaxisandyaxisEditFieldLabel = uilabel(app.UIFigure);
            app.MomentofinertiaonxaxisandyaxisEditFieldLabel.Text = {'Moment of inertia on'; 'x-axis and y-axis: '};
            app.MomentXY = uieditfield(app.UIFigure, 'text');
            app.MomentofinertiaoncentroidalaxisLabel = uilabel(app.UIFigure);
            app.MomentofinertiaoncentroidalaxisLabel.Text = {'Moment of inertia on'; 'centroidal axis:'};
            app.MomentC = uieditfield(app.UIFigure, 'text');
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.ENTERINPUTSOFMAINPOLYGONLabel = uilabel(app.UIFigure);
            app.ENTERINPUTSOFMAINPOLYGONLabel.Text = 'ENTER INPUTS OF MAIN POLYGON: ';
            app.OUTPUTLabel = uilabel(app.UIFigure);
            app.OUTPUTLabel.Text = 'OUTPUT:';
            app.ENTERINPUTSOFINNERPOLYGONLabel = uilabel(app.UIFigure);
            app.ENTERINPUTSOFINNERPOLYGONLabel.Text = 'ENTER INPUTS OF INNER POLYGON:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PolywithPoly

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end