classdef Rectangle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        EnterxcoordinateofALabel  matlab.ui.control.Label
        Ax                        matlab.ui.control.NumericEditField
        EnterxcoordinateofBEditFieldLabel  matlab.ui.control.Label
        Bx                        matlab.ui.control.NumericEditField
        EnterycoordinateofAEditFieldLabel  matlab.ui.control.Label
        Ay                        matlab.ui.control.NumericEditField
        EnterycoordinateofBEditFieldLabel  matlab.ui.control.Label
        By                        matlab.ui.control.NumericEditField
        UIAxes                    matlab.ui.control.UIAxes
        EnterthecoordinatesLabel  matlab.ui.control.Label
        EnterxcoordinateofCEditFieldLabel  matlab.ui.control.Label
        Cx                        matlab.ui.control.NumericEditField
        EnterycoordinateofCEditFieldLabel  matlab.ui.control.Label
        Cy                        matlab.ui.control.NumericEditField
        EnterxcoordinateofDEditFieldLabel  matlab.ui.control.Label
        Dx                        matlab.ui.control.NumericEditField
        EnterycoordinateofDEditFieldLabel  matlab.ui.control.Label
        Dy                        matlab.ui.control.NumericEditField
        THECENTROIDOFTHEFIGUREISATLabel  matlab.ui.control.Label
        xLabel                    matlab.ui.control.Label
        OutX                      matlab.ui.control.NumericEditField
        yEditFieldLabel           matlab.ui.control.Label
        OutY                      matlab.ui.control.NumericEditField
        CALCULATEButton           matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CALCULATEButton
        function CALCULATEButtonPushed(app, event)
            ax=app.Ax.Value;
            ay=app.Ax.Value;
            bx=app.Bx.Value;
            by=app.By.Value;
            cx=app.Cx.Value;
            cy=app.Cy.Value;
            dx=app.Dx.Value;
            dy=app.Dy.Value;
            
            if(ax==3)
                MainCode
            end
            
            a=zeros(1,2);
            c1=a;
            c2=a;
            c3=a;
            c4=a;

            c1(1,1)=ax;
            c1(1,2)=ay;

            c2(1,1)=bx;
            c2(1,2)=by;

            c3(1,1)=cx;
            c3(1,2)=cy;

            c4(1,1)=dx;
            c4(1,2)=dy;
            
            m12= (c1(:) + c2(:)).'/2;
            m34= (c3(:) + c4(:)).'/2;
            
            Centroid= (m34(:) + m12(:)).'/2;
            
            hold(app.UIAxes, 'on');
            plot(app.UIAxes,ax,ay);
            plot(app.UIAxes,bx,by);
            plot(app.UIAxes,cx,cy);
            plot(app.UIAxes,dx,dy);
            
            app.OutX.Value = Centroid(1,1);
            app.OutY.Value = Centroid(1,2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.EnterxcoordinateofALabel = uilabel(app.UIFigure);
            app.EnterxcoordinateofALabel.Text = 'Enter x-coordinate of A:';
            app.Ax = uieditfield(app.UIFigure, 'numeric');
            app.EnterxcoordinateofBEditFieldLabel = uilabel(app.UIFigure);
            app.EnterxcoordinateofBEditFieldLabel.Text = 'Enter x-coordinate of B:';
            app.Bx = uieditfield(app.UIFigure, 'numeric');
            app.EnterycoordinateofAEditFieldLabel = uilabel(app.UIFigure);
            app.EnterycoordinateofAEditFieldLabel.Text = 'Enter y-coordinate of A:';
            app.Ay = uieditfield(app.UIFigure, 'numeric');
            app.EnterycoordinateofBEditFieldLabel = uilabel(app.UIFigure);
            app.EnterycoordinateofBEditFieldLabel.Text = 'Enter y-coordinate of B:';
            app.By = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Your input')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.XTickLabel = {'0'; '2'; '4'; '6'; '8'; '10'};
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTickLabel = {'0'; '2'; '4'; '6'; '8'; '10'};
            app.EnterthecoordinatesLabel = uilabel(app.UIFigure);
            app.EnterthecoordinatesLabel.Text = 'Enter the coordinates:';
            app.EnterxcoordinateofCEditFieldLabel = uilabel(app.UIFigure);
            app.EnterxcoordinateofCEditFieldLabel.Text = 'Enter x-coordinate of C:';
            app.Cx = uieditfield(app.UIFigure, 'numeric');
            app.EnterycoordinateofCEditFieldLabel = uilabel(app.UIFigure);
            app.EnterycoordinateofCEditFieldLabel.Text = 'Enter y-coordinate of C:';
            app.Cy = uieditfield(app.UIFigure, 'numeric');
            app.EnterxcoordinateofDEditFieldLabel = uilabel(app.UIFigure);
            app.EnterxcoordinateofDEditFieldLabel.Text = 'Enter x-coordinate of D:';
            app.Dx = uieditfield(app.UIFigure, 'numeric');
            app.EnterycoordinateofDEditFieldLabel = uilabel(app.UIFigure);
            app.EnterycoordinateofDEditFieldLabel.Text = 'Enter y-coordinate of D:';
            app.Dy = uieditfield(app.UIFigure, 'numeric');
            app.THECENTROIDOFTHEFIGUREISATLabel = uilabel(app.UIFigure);
            app.THECENTROIDOFTHEFIGUREISATLabel.Text = {'THE CENTROID OF THE FIGURE IS AT:'; ''};
            app.xLabel = uilabel(app.UIFigure);
            app.xLabel.Text = 'x:';
            app.OutX = uieditfield(app.UIFigure, 'numeric');
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y:';
            app.OutY = uieditfield(app.UIFigure, 'numeric');
            app.CALCULATEButton = uibutton(app.UIFigure, 'push');
            app.CALCULATEButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULATEButtonPushed, true);
            app.CALCULATEButton.Text = 'CALCULATE!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Rectangle

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end